classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        NEXAARFlightTOMATOSOUPSInterfaceLabel  matlab.ui.control.Label
        StartProcessingButton        matlab.ui.control.Button
        ModelSelectionDropDown       matlab.ui.control.DropDown
        ModelSelectionDropDownLabel  matlab.ui.control.Label
        SelectVideoFileButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        FolderPath char
        MethodChoice char
        OutputFromFirstFunction
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectVideoFileButton
        function SelectVideoFileButtonPushed(app, event)

            [file, path] = uigetfile({'*.*', 'All Files'}, 'Select a File');
            if isequal(file,0)
                uialert(app.UIFigure, 'No file selected.', 'Warning');
            else
                app.FolderPath = fullfile(path, file); % Save full file path now
                uialert(app.UIFigure, ['File Selected: ' file], 'File Selection');
            end

        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            if isempty(app.FolderPath)
                uialert(app.UIFigure, 'Please select a file first.', 'Error');  % Changed wording
                return;
            end

            app.MethodChoice = app.MethodDropDown.Value;

            % Based on selection, call the right function
            switch app.MethodChoice
                case 'Manual Threshold Bounding'
                    app.OutputFromFirstFunction = NEXAARMVPV6(app.FolderPath);  % Using full file path
                case 'SAM AI Model'
                    app.OutputFromFirstFunction = NEXAARMVPSAMAI(app.FolderPath);  % Using full file path
                otherwise
                    uialert(app.UIFigure, 'Invalid selection.', 'Error');
                    return;
            end

            % Prompt for extra data (adjustable input)
            camInfo = CamInfo(app.OutputFromFirstFunction, camNum);
            promptData = camData(camInfo);

            % Call the next function with the output and additional data
            finalProcessing(app.OutputFromFirstFunction, promptData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectVideoFileButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoFileButtonPushed, true);
            app.SelectVideoFileButton.Tag = 'SelectFolderButton';
            app.SelectVideoFileButton.Text = 'Select Video File';
            app.ModelSelectionDropDownLabel = uilabel(app.UIFigure);
            app.ModelSelectionDropDownLabel.Text = 'Model Selection';
            app.ModelSelectionDropDown = uidropdown(app.UIFigure);
            app.ModelSelectionDropDown.Items = {'Manual Threshold Bounding', 'SAM AI'};
            app.ModelSelectionDropDown.Tag = 'MethodDropDown';
            app.ModelSelectionDropDown.Placeholder = 'Model';
            app.ModelSelectionDropDown.Value = 'Manual Threshold Bounding';
            app.StartProcessingButton = uibutton(app.UIFigure, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Tag = 'StartProcessingButton';
            app.StartProcessingButton.Text = 'Start Processing';
            app.NEXAARFlightTOMATOSOUPSInterfaceLabel = uilabel(app.UIFigure);
            app.NEXAARFlightTOMATOSOUPSInterfaceLabel.Text = 'NEXAAR Flight: TOMATOSOUPS Interface';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tomatosoups-logo-proposal-bage.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end